classdef dronegen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GPSXYTab                        matlab.ui.container.Tab
        XYoriginLabel                   matlab.ui.control.Label
        GPSandXYconverterLabel          matlab.ui.control.Label
        xmetersEditFieldLabel           matlab.ui.control.Label
        xmetersEditField                matlab.ui.control.NumericEditField
        ymetersLabel                    matlab.ui.control.Label
        ymetersEditField                matlab.ui.control.NumericEditField
        LatEditFieldLabel               matlab.ui.control.Label
        LatEditField                    matlab.ui.control.NumericEditField
        LonEditFieldLabel               matlab.ui.control.Label
        LonEditField                    matlab.ui.control.NumericEditField
        LatEditField_2Label             matlab.ui.control.Label
        LatEditField_2                  matlab.ui.control.NumericEditField
        LonEditField_2Label             matlab.ui.control.Label
        LonEditField_2                  matlab.ui.control.NumericEditField
        AzimuthTab                      matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        GenerateWPfileButton_2          matlab.ui.control.Button
        CalculateButton_2               matlab.ui.control.Button
        RadiusmEditField_2Label         matlab.ui.control.Label
        RadiusmEditField_2              matlab.ui.control.NumericEditField
        PointsEditField_2Label          matlab.ui.control.Label
        PointsEditField_2               matlab.ui.control.NumericEditField
        HeightmEditFieldLabel           matlab.ui.control.Label
        HeightmEditField                matlab.ui.control.NumericEditField
        AntOffsetmEditField_2Label      matlab.ui.control.Label
        AntOffsetmEditField_2           matlab.ui.control.NumericEditField
        BatteryLifeminEditField_2Label  matlab.ui.control.Label
        BatteryLifeminEditField_2       matlab.ui.control.NumericEditField
        PathLengthmEditField_2Label     matlab.ui.control.Label
        PathLengthmEditField_2          matlab.ui.control.NumericEditField
        SpeedmsEditField_2Label         matlab.ui.control.Label
        SpeedmsEditField_2              matlab.ui.control.NumericEditField
        AngleBetweenPointsEditField_2Label  matlab.ui.control.Label
        AngleBetweenPointsEditField_2   matlab.ui.control.NumericEditField
        PathAngleEditFieldLabel         matlab.ui.control.Label
        PathAngleEditField              matlab.ui.control.NumericEditField
        ElevationTab                    matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        RadiusmEditFieldLabel           matlab.ui.control.Label
        RadiusmEditField                matlab.ui.control.NumericEditField
        PointsEditFieldLabel            matlab.ui.control.Label
        PointsEditField                 matlab.ui.control.NumericEditField
        HeadingdegEditFieldLabel        matlab.ui.control.Label
        HeadingdegEditField             matlab.ui.control.NumericEditField
        AntOffsetmEditFieldLabel        matlab.ui.control.Label
        AntOffsetmEditField             matlab.ui.control.NumericEditField
        BatteryLifeminEditFieldLabel    matlab.ui.control.Label
        BatteryLifeminEditField         matlab.ui.control.NumericEditField
        GenerateWPfileButton            matlab.ui.control.Button
        PathLengthmEditFieldLabel       matlab.ui.control.Label
        PathLengthmEditField            matlab.ui.control.NumericEditField
        SpeedmsEditFieldLabel           matlab.ui.control.Label
        SpeedmsEditField                matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        AngleBetweenPointsEditFieldLabel  matlab.ui.control.Label
        AngleBetweenPointsEditField     matlab.ui.control.NumericEditField
        SettingsTab                     matlab.ui.container.Tab
        ReferenceLatitudeEditFieldLabel  matlab.ui.control.Label
        ReferenceLatitudeEditField      matlab.ui.control.NumericEditField
        ReferenceLongitudeEditFieldLabel  matlab.ui.control.Label
        ReferenceLongitudeEditField     matlab.ui.control.NumericEditField
        ReferenceXEditFieldLabel        matlab.ui.control.Label
        ReferenceXEditField             matlab.ui.control.NumericEditField
        ReferenceYEditFieldLabel        matlab.ui.control.Label
        ReferenceYEditField             matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        wps;
        gps;
    end

    methods (Access = public)
    
        function [Lat, Lon] = xyz2LatLon(origin_Lat, origin_Lon, origin_x, origin_y, x, y)
            % Convert the distances between several xyz locations to Lat/Lon distances
            % relative to the first location
            
            %% if only x and y input arguments, relative to V1
            if nargin == 2
                x = origin_Lat;
                y = origin_Lon;
                origin_x = app.ReferenceXEditField.Value;
                origin_y = app.ReferenceYEditField.Value;
                origin_Lat = app.ReferenceLatitudeEditField.Value;
                origin_Lon = app.ReferenceLongitudeEditField.Value;
            end
            
            %% Radius of the Earth in meters
            RE = 6371000;
            
            %% Convert degrees to distances
            dx = (pi/180)*RE*cosd(mean(origin_Lat));
            dy = (pi/180)*RE;
            
            %% AUT origin in absolute meters
            abs_origin_x_m = origin_x + (origin_Lon * dx);
            abs_origin_y_m = origin_y + (origin_Lat * dy);
            
            %% Relative abs
            abs_x_m = abs_origin_x_m + x;
            abs_y_m = abs_origin_y_m + y;
            
            %% Reference meters to GPS coordinates
            Lon = abs_x_m / dx;
            Lat = abs_y_m / dy;
        end

    end


    methods (Access = private)

        % Value changed function: xmetersEditField
        function xmetersEditFieldValueChanged(app, event)
            x = app.xmetersEditField.Value;
            y = app.ymetersEditField.Value;

            %% Radius of the Earth in meters
            RE = 6371000;
            
            %% Convert degrees to distances
            dx = (pi/180)*RE*cosd(app.LatEditField_2.Value);
            dy = (pi/180)*RE;
            
            %% AUT origin in absolute meters
            abs_origin_x_m = (app.LonEditField_2.Value * dx);
            abs_origin_y_m = (app.LatEditField_2.Value * dy);
            
            %% Relative abs
            abs_x_m = abs_origin_x_m + x;
            abs_y_m = abs_origin_y_m + y;
            
            %% Reference meters to GPS coordinates
            app.LonEditField.Value = abs_x_m / dx;
            app.LatEditField.Value = abs_y_m / dy;
        end

        % Value changed function: ymetersEditField
        function ymetersEditFieldValueChanged(app, event)
            x = app.xmetersEditField.Value;
            y = app.ymetersEditField.Value;
           
            %% Radius of the Earth in meters
            RE = 6371000;
            
            %% Convert degrees to distances
            dx = (pi/180)*RE*cosd(app.LatEditField_2.Value);
            dy = (pi/180)*RE;
            
            %% AUT origin in absolute meters
            abs_origin_x_m = (app.LonEditField_2.Value * dx);
            abs_origin_y_m = (app.LatEditField_2.Value * dy);
            
            %% Relative abs
            abs_x_m = abs_origin_x_m + x;
            abs_y_m = abs_origin_y_m + y;
            
            %% Reference meters to GPS coordinates
            app.LonEditField.Value = abs_x_m / dx;
            app.LatEditField.Value = abs_y_m / dy;
        end

        % Value changed function: LatEditField
        function LatEditFieldValueChanged(app, event)
            %% Radius of the Earth in meters
            RE = 6371000;
            
            dx = (pi/180)*RE*cosd(app.LatEditField_2.Value);
            dy = (pi/180)*RE;
            
            abs_origin_x_m = (app.LonEditField_2.Value * dx);
            abs_origin_y_m = (app.LatEditField_2.Value * dy);
            
            Lon_m = app.LonEditField.Value*dx;
            Lat_m = app.LatEditField.Value*dy;
            
            app.xmetersEditField.Value = Lon_m - abs_origin_x_m;
            app.ymetersEditField.Value = Lat_m - abs_origin_y_m;
        end

        % Value changed function: LonEditField
        function LonEditFieldValueChanged(app, event)
            %% Radius of the Earth in meters
            RE = 6371000;
            
            dx = (pi/180)*RE*cosd(app.LatEditField_2.Value);
            dy = (pi/180)*RE;
            
            abs_origin_x_m = (app.LonEditField_2.Value * dx);
            abs_origin_y_m = (app.LatEditField_2.Value * dy);
            
            Lon_m = app.LonEditField.Value*dx;
            Lat_m = app.LatEditField.Value*dy;
            
            app.xmetersEditField.Value = Lon_m - abs_origin_x_m;
            app.ymetersEditField.Value = Lat_m - abs_origin_y_m;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % reference location
            aut_Lat = app.ReferenceLatitudeEditField.Value;
            aut_Lon = app.ReferenceLongitudeEditField.Value;
            aut_x = app.ReferenceXEditField.Value;
            aut_y = app.ReferenceYEditField.Value;
            
            % convert to radians
            heading_rad = (pi/180) * app.HeadingdegEditField.Value;
            
            % circle stuff
            app.PathLengthmEditField.Value = 0.5 * pi * app.RadiusmEditField.Value;
            step_angle = (pi/2) / (app.PointsEditField.Value-1) * [0:app.PointsEditField.Value-1];
            app.AngleBetweenPointsEditField.Value = step_angle(1,2) * (180/pi); % in degrees
            xy_radius = cos(step_angle) * app.RadiusmEditField.Value;
            z = fliplr(xy_radius) - app.AntOffsetmEditField.Value;
            
            % create and populate waypoint array
            app.wps = zeros(app.PointsEditField.Value,3);
            app.wps(:,1) = xy_radius * cos(heading_rad);
            app.wps(:,2) = xy_radius * sin(heading_rad);
            app.wps(:,3) = z;
            
            app.wps = flipud(app.wps);
            
            % prepare GPS array
            app.gps = zeros(length(app.wps),2);
            
            % convert to GPS coordinates
            [app.gps(:,1),app.gps(:,2)] = xyz2LatLon(aut_Lat, aut_Lon, aut_x, aut_y, app.wps(:,1), app.wps(:,2));
            
            % flight paramaters
            app.SpeedmsEditField.Value = app.PathLengthmEditField.Value / (app.BatteryLifeminEditField.Value * 60); % m/s
            
            ax = app.UIAxes;
            plot(ax,app.gps(:,2),app.gps(:,1),'kh');
            hold(ax,'on'), grid(ax,'on')
            daspect(ax,[1 cosd(mean(aut_Lat)) 1]);
            pbaspect(ax,[3 2 1]);
            hold(ax,'off')
        end

        % Button pushed function: GenerateWPfileButton
        function GenerateWPfileButtonPushed(app, event)
            % mission planner waypoint file generation
            coord_frame = 3;
            command = 16; % waypoint
            autocontinue = 1;
            
            mpwpf = zeros(length(app.wps),12);
            mpwpf(:,1) = ([1:length(app.wps)]'); % index
            mpwpf(:,3) = coord_frame * ones(length(app.wps),1); % coordinate frame
            mpwpf(:,4) = command * ones(length(app.wps),1); % command
            mpwpf(:,9) = app.gps(:,1); % lattitude
            mpwpf(:,10) = app.gps(:,2); % longitude
            mpwpf(:,11) = app.wps(:,3); % altitude
            mpwpf(:,12) = autocontinue * ones(length(app.wps),1); % autocontinue
            
            if exist('elevation_cut.txt','file') == 2
                delete('elevation_cut.txt');
            else
            end
            
            % header
            fileID = fopen('elevation_cut.txt', 'wt');
            fprintf(fileID,'QGC WPL 110\n');
            fclose(fileID);
            
            % GPS data
            dlmwrite('elevation_cut.txt',mpwpf,'delimiter','\t','newline','pc','precision',12,'-append');
        end

        % Button pushed function: GenerateWPfileButton_2
        function GenerateWPfileButton_2Pushed(app, event)
            % mission planner waypoint file generation
            coord_frame = 3;
            command = 16; % waypoint
            autocontinue = 1;
            
            mpwpf = zeros(length(app.wps),12);
            mpwpf(:,1) = ([1:length(app.wps)]'); % index
            mpwpf(:,3) = coord_frame * ones(length(app.wps),1); % coordinate frame
            mpwpf(:,4) = command * ones(length(app.wps),1); % command
            mpwpf(:,9) = app.gps(:,1); % lattitude
            mpwpf(:,10) = app.gps(:,2); % longitude
            mpwpf(:,11) = app.wps(:,3); % altitude
            mpwpf(:,12) = autocontinue * ones(length(app.wps),1); % autocontinue
            
            if exist('azimuth_cut.txt','file') == 2
                delete('azimuth_cut.txt');
            else
            end
            
            % header
            fileID = fopen('azimuth_cut.txt', 'wt');
            fprintf(fileID,'QGC WPL 110\n');
            fclose(fileID);
            
            % GPS data
            dlmwrite('azimuth_cut.txt',mpwpf,'delimiter','\t','newline','pc','precision',12,'-append');
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            % reference location
            aut_Lat = app.ReferenceLatitudeEditField.Value;
            aut_Lon = app.ReferenceLongitudeEditField.Value;
            aut_x = app.ReferenceXEditField.Value;
            aut_y = app.ReferenceYEditField.Value;
            
            % convert to radians
            %heading_rad = (pi/180) * app.HeadingdegEditField.Value;
            
            % circle stuff
            angle = (2*pi)/app.PointsEditField_2.Value*[1:app.PointsEditField_2.Value];
            circumference = 2 * pi * app.RadiusmEditField_2.Value;
            app.PathLengthmEditField_2.Value = circumference * (app.PathAngleEditField.Value / 360);
            app.AngleBetweenPointsEditField_2.Value = 360 / app.PointsEditField_2.Value;
            
            % create and populate waypoint array
            app.wps = zeros(app.PointsEditField_2.Value,3);
            app.wps(:,1) = aut_x + app.RadiusmEditField_2.Value * cos(angle);
            app.wps(:,2) = aut_y + app.RadiusmEditField_2.Value * sin(angle);
            app.wps(:,3) = ones(app.PointsEditField_2.Value,1) * app.HeightmEditField.Value - app.AntOffsetmEditField_2.Value;
            
            % prepare GPS array
            app.gps = zeros(length(app.wps),2);
            
            % convert to GPS coordinates
            [app.gps(:,1),app.gps(:,2)] = xyz2LatLon(aut_Lat, aut_Lon, aut_x, aut_y, app.wps(:,1), app.wps(:,2));
            
            % flight paramaters
            app.SpeedmsEditField_2.Value = app.PathLengthmEditField_2.Value / (app.BatteryLifeminEditField_2.Value * 60); % m/s
            
            ax = app.UIAxes_2;
            plot(ax,app.gps(:,2),app.gps(:,1),'kh');
            hold(ax,'on'), grid(ax,'on')
            daspect(ax,[1 cosd(mean(aut_Lat)) 1]);
            pbaspect(ax,[3 2 1]);
            hold(ax,'off')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GPSXYTab = uitab(app.TabGroup);
            app.GPSXYTab.Title = 'GPS XY';
            app.XYoriginLabel = uilabel(app.GPSXYTab);
            app.XYoriginLabel.Text = 'XY origin:';
            app.GPSandXYconverterLabel = uilabel(app.GPSXYTab);
            app.GPSandXYconverterLabel.Text = 'GPS and XY converter';
            app.xmetersEditFieldLabel = uilabel(app.GPSXYTab);
            app.xmetersEditFieldLabel.Text = 'x (meters)';
            app.xmetersEditField = uieditfield(app.GPSXYTab, 'numeric');
            app.xmetersEditField.ValueDisplayFormat = '%.2f';
            app.xmetersEditField.ValueChangedFcn = createCallbackFcn(app, @xmetersEditFieldValueChanged, true);
            app.ymetersLabel = uilabel(app.GPSXYTab);
            app.ymetersLabel.Text = 'y (meters)';
            app.ymetersEditField = uieditfield(app.GPSXYTab, 'numeric');
            app.ymetersEditField.ValueDisplayFormat = '%.2f';
            app.ymetersEditField.ValueChangedFcn = createCallbackFcn(app, @ymetersEditFieldValueChanged, true);
            app.LatEditFieldLabel = uilabel(app.GPSXYTab);
            app.LatEditFieldLabel.Text = 'Lat';
            app.LatEditField = uieditfield(app.GPSXYTab, 'numeric');
            app.LatEditField.Limits = [-90 90];
            app.LatEditField.ValueDisplayFormat = '%.6f';
            app.LatEditField.ValueChangedFcn = createCallbackFcn(app, @LatEditFieldValueChanged, true);
            app.LatEditField.Value = 40.70925;
            app.LonEditFieldLabel = uilabel(app.GPSXYTab);
            app.LonEditFieldLabel.Text = 'Lon';
            app.LonEditField = uieditfield(app.GPSXYTab, 'numeric');
            app.LonEditField.Limits = [-180 180];
            app.LonEditField.ValueDisplayFormat = '%.6f';
            app.LonEditField.ValueChangedFcn = createCallbackFcn(app, @LonEditFieldValueChanged, true);
            app.LonEditField.Value = -77.9689;
            app.LatEditField_2Label = uilabel(app.GPSXYTab);
            app.LatEditField_2Label.Text = 'Lat';
            app.LatEditField_2 = uieditfield(app.GPSXYTab, 'numeric');
            app.LatEditField_2.Limits = [-90 90];
            app.LatEditField_2.ValueDisplayFormat = '%.6f';
            app.LatEditField_2.Value = 40.70925;
            app.LonEditField_2Label = uilabel(app.GPSXYTab);
            app.LonEditField_2Label.Text = 'Lon';
            app.LonEditField_2 = uieditfield(app.GPSXYTab, 'numeric');
            app.LonEditField_2.Limits = [-180 180];
            app.LonEditField_2.ValueDisplayFormat = '%.6f';
            app.LonEditField_2.Value = -77.9689;
            app.AzimuthTab = uitab(app.TabGroup);
            app.AzimuthTab.Title = 'Azimuth';
            app.UIAxes_2 = uiaxes(app.AzimuthTab);
            title(app.UIAxes_2, 'Waypoints')
            xlabel(app.UIAxes_2, 'Lon')
            ylabel(app.UIAxes_2, 'Lat')
            app.GenerateWPfileButton_2 = uibutton(app.AzimuthTab, 'push');
            app.GenerateWPfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateWPfileButton_2Pushed, true);
            app.GenerateWPfileButton_2.Text = 'Generate WP file';
            app.CalculateButton_2 = uibutton(app.AzimuthTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.RadiusmEditField_2Label = uilabel(app.AzimuthTab);
            app.RadiusmEditField_2Label.Text = 'Radius (m)';
            app.RadiusmEditField_2 = uieditfield(app.AzimuthTab, 'numeric');
            app.RadiusmEditField_2.Limits = [1 2000];
            app.RadiusmEditField_2.ValueDisplayFormat = '%.2f';
            app.RadiusmEditField_2.Value = 114.567;
            app.PointsEditField_2Label = uilabel(app.AzimuthTab);
            app.PointsEditField_2Label.Text = 'Points';
            app.PointsEditField_2 = uieditfield(app.AzimuthTab, 'numeric');
            app.PointsEditField_2.Limits = [2 1000];
            app.PointsEditField_2.ValueDisplayFormat = '%.0f';
            app.PointsEditField_2.Value = 100;
            app.HeightmEditFieldLabel = uilabel(app.AzimuthTab);
            app.HeightmEditFieldLabel.Text = 'Height (m)';
            app.HeightmEditField = uieditfield(app.AzimuthTab, 'numeric');
            app.HeightmEditField.Limits = [0 122];
            app.HeightmEditField.Value = 41.699;
            app.AntOffsetmEditField_2Label = uilabel(app.AzimuthTab);
            app.AntOffsetmEditField_2Label.Text = 'Ant. Offset (m)';
            app.AntOffsetmEditField_2 = uieditfield(app.AzimuthTab, 'numeric');
            app.AntOffsetmEditField_2.Limits = [-20 10];
            app.AntOffsetmEditField_2.Value = -3;
            app.BatteryLifeminEditField_2Label = uilabel(app.AzimuthTab);
            app.BatteryLifeminEditField_2Label.Text = 'Battery Life (min)';
            app.BatteryLifeminEditField_2 = uieditfield(app.AzimuthTab, 'numeric');
            app.BatteryLifeminEditField_2.Limits = [1 30];
            app.BatteryLifeminEditField_2.Value = 8;
            app.PathLengthmEditField_2Label = uilabel(app.AzimuthTab);
            app.PathLengthmEditField_2Label.Text = 'Path Length (m)';
            app.PathLengthmEditField_2 = uieditfield(app.AzimuthTab, 'numeric');
            app.SpeedmsEditField_2Label = uilabel(app.AzimuthTab);
            app.SpeedmsEditField_2Label.Text = 'Speed (m/s)';
            app.SpeedmsEditField_2 = uieditfield(app.AzimuthTab, 'numeric');
            app.AngleBetweenPointsEditField_2Label = uilabel(app.AzimuthTab);
            app.AngleBetweenPointsEditField_2Label.Text = 'Angle Between Points';
            app.AngleBetweenPointsEditField_2 = uieditfield(app.AzimuthTab, 'numeric');
            app.PathAngleEditFieldLabel = uilabel(app.AzimuthTab);
            app.PathAngleEditFieldLabel.Text = 'Path Angle';
            app.PathAngleEditField = uieditfield(app.AzimuthTab, 'numeric');
            app.PathAngleEditField.Limits = [0 360];
            app.PathAngleEditField.Value = 120;
            app.ElevationTab = uitab(app.TabGroup);
            app.ElevationTab.Title = 'Elevation';
            app.UIAxes = uiaxes(app.ElevationTab);
            title(app.UIAxes, 'Waypoints')
            xlabel(app.UIAxes, 'Lon')
            ylabel(app.UIAxes, 'Lat')
            app.RadiusmEditFieldLabel = uilabel(app.ElevationTab);
            app.RadiusmEditFieldLabel.Text = 'Radius (m)';
            app.RadiusmEditField = uieditfield(app.ElevationTab, 'numeric');
            app.RadiusmEditField.Limits = [1 2000];
            app.RadiusmEditField.ValueDisplayFormat = '%.2f';
            app.RadiusmEditField.Value = 121.92;
            app.PointsEditFieldLabel = uilabel(app.ElevationTab);
            app.PointsEditFieldLabel.Text = 'Points';
            app.PointsEditField = uieditfield(app.ElevationTab, 'numeric');
            app.PointsEditField.Limits = [1 1000];
            app.PointsEditField.ValueDisplayFormat = '%.0f';
            app.PointsEditField.Value = 25;
            app.HeadingdegEditFieldLabel = uilabel(app.ElevationTab);
            app.HeadingdegEditFieldLabel.Text = 'Heading (deg)';
            app.HeadingdegEditField = uieditfield(app.ElevationTab, 'numeric');
            app.HeadingdegEditField.Limits = [-360 360];
            app.HeadingdegEditField.Value = 125;
            app.AntOffsetmEditFieldLabel = uilabel(app.ElevationTab);
            app.AntOffsetmEditFieldLabel.Text = 'Ant. Offset (m)';
            app.AntOffsetmEditField = uieditfield(app.ElevationTab, 'numeric');
            app.AntOffsetmEditField.Limits = [-20 10];
            app.AntOffsetmEditField.Value = -3;
            app.BatteryLifeminEditFieldLabel = uilabel(app.ElevationTab);
            app.BatteryLifeminEditFieldLabel.Text = 'Battery Life (min)';
            app.BatteryLifeminEditField = uieditfield(app.ElevationTab, 'numeric');
            app.BatteryLifeminEditField.Limits = [1 30];
            app.BatteryLifeminEditField.Value = 8;
            app.GenerateWPfileButton = uibutton(app.ElevationTab, 'push');
            app.GenerateWPfileButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateWPfileButtonPushed, true);
            app.GenerateWPfileButton.Text = 'Generate WP file';
            app.PathLengthmEditFieldLabel = uilabel(app.ElevationTab);
            app.PathLengthmEditFieldLabel.Text = 'Path Length (m)';
            app.PathLengthmEditField = uieditfield(app.ElevationTab, 'numeric');
            app.SpeedmsEditFieldLabel = uilabel(app.ElevationTab);
            app.SpeedmsEditFieldLabel.Text = 'Speed (m/s)';
            app.SpeedmsEditField = uieditfield(app.ElevationTab, 'numeric');
            app.CalculateButton = uibutton(app.ElevationTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.AngleBetweenPointsEditFieldLabel = uilabel(app.ElevationTab);
            app.AngleBetweenPointsEditFieldLabel.Text = 'Angle Between Points';
            app.AngleBetweenPointsEditField = uieditfield(app.ElevationTab, 'numeric');
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ReferenceLatitudeEditFieldLabel = uilabel(app.SettingsTab);
            app.ReferenceLatitudeEditFieldLabel.Text = 'Reference Latitude';
            app.ReferenceLatitudeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ReferenceLatitudeEditField.Limits = [-90 90];
            app.ReferenceLatitudeEditField.ValueDisplayFormat = '%.6f';
            app.ReferenceLatitudeEditField.Value = 40.708775;
            app.ReferenceLongitudeEditFieldLabel = uilabel(app.SettingsTab);
            app.ReferenceLongitudeEditFieldLabel.Text = 'Reference Longitude';
            app.ReferenceLongitudeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ReferenceLongitudeEditField.Limits = [-180 180];
            app.ReferenceLongitudeEditField.ValueDisplayFormat = '%.6f';
            app.ReferenceLongitudeEditField.Value = -77.96869;
            app.ReferenceXEditFieldLabel = uilabel(app.SettingsTab);
            app.ReferenceXEditFieldLabel.Text = 'Reference X';
            app.ReferenceXEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ReferenceXEditField.ValueDisplayFormat = '%.2f';
            app.ReferenceYEditFieldLabel = uilabel(app.SettingsTab);
            app.ReferenceYEditFieldLabel.Text = 'Reference Y';
            app.ReferenceYEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ReferenceYEditField.ValueDisplayFormat = '%.2f';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dronegen

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end